classdef MatsLab2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UITable                       matlab.ui.control.Table
        PickYourMaterialButtonGroup   matlab.ui.container.ButtonGroup
        GrapheneButton                matlab.ui.control.RadioButton
        MoS2Button                    matlab.ui.control.RadioButton
        PhosphoreneButton             matlab.ui.control.RadioButton
        WS2Button                     matlab.ui.control.RadioButton
        SiliconCheckBox               matlab.ui.control.CheckBox
        NoneButton                    matlab.ui.control.RadioButton
        BaselineButton                matlab.ui.control.Button
        FitButton                     matlab.ui.control.Button
        RamanBaselineandFitToolboxLabel  matlab.ui.control.Label
        ImportDataButton              matlab.ui.control.Button
        SmoothnessParameterEditFieldLabel  matlab.ui.control.Label
        SmoothnessParameterEditField  matlab.ui.control.NumericEditField
        MinimumDifferenceEditFieldLabel  matlab.ui.control.Label
        MinimumDifferenceEditField    matlab.ui.control.NumericEditField
        ButtonGroup                   matlab.ui.container.ButtonGroup
        ValuesButton                  matlab.ui.control.RadioButton
        ErrorsButton                  matlab.ui.control.RadioButton
    end


    methods (Access = private)

        % Button pushed function: ImportDataButton
        function uiimportdata(app, event)
            filename = uigetfile;
            datatable = readtable(filename);
            dataarray = table2array(datatable);
            arraylength = length(dataarray);
            x = dataarray(1:arraylength,1);
            y = dataarray(1:arraylength,2);
            xmin = min(x);
            xmax = max(x);
            ymin = min(y) - 100;
            ymax = max(y);
            plot(app.UIAxes,x,y)
            app.UIAxes.XLim = [xmin xmax];
            app.UIAxes.YLim = [ymin ymax];
            setappdata(app.UIFigure,'xtransfer',x);
            setappdata(app.UIFigure,'ytransfer',y);
        end

        % Button pushed function: BaselineButton
        function uibaseline(app, event)
            % Initialize baselining parameters
            ORDER = 2;
            MAX_ITER = 100;
            smoothness_param = app.SmoothnessParameterEditField.Value;
            min_diff = app.MinimumDifferenceEditField.Value;
            xdata = getappdata(app.UIFigure,'xtransfer');
            ydata = getappdata(app.UIFigure,'ytransfer');
            signal_length = length(ydata);
            difference_matrix = diff(speye(signal_length), ORDER);
            minimization_matrix = (smoothness_param*difference_matrix')*difference_matrix;
            penalty_vector = ones(signal_length,1);
            
            % Execute baselining
            for count = 1:MAX_ITER
                penalty_matrix = spdiags(penalty_vector, 0, signal_length, signal_length);
                % Cholesky decomposition
                C = chol(penalty_matrix + minimization_matrix);
                baseline = C \ (C'\(penalty_vector.*ydata));
                d = ydata - baseline;
                % make d-, and get penalty_vector^t with m and s
                dn = d(d<0);
                m = mean(dn);
                s = std(dn);
                penalty_vector_temp = 1./(1+exp(2*(d-(2*s-m))/s));
                % check exit condition and backup
                if norm(penalty_vector-penalty_vector_temp)/norm(penalty_vector) < min_diff
                %         count
                    break;
                end
                penalty_vector = penalty_vector_temp;
            end
            baselineddata = [xdata,ydata - baseline]
            BLarraylength = length(baselineddata);
            xBL = baselineddata(1:BLarraylength,1);
            yBL = baselineddata(1:BLarraylength,2);
            xmin = min(xBL);
            xmax = max(xBL);
            ymin = min(yBL) - 100;
            ymax = max(yBL);
            plot(app.UIAxes,xBL,yBL)
            app.UIAxes.XLim = [xmin xmax];
            app.UIAxes.YLim = [ymin ymax];
            setappdata(app.UIFigure,'xtransfer2',xBL);
            setappdata(app.UIFigure,'ytransfer2',yBL);
        end

        % Selection changed function: PickYourMaterialButtonGroup
        function uipickmaterial(app, event)
            selectedButton = app.PickYourMaterialButtonGroup.SelectedObject;
            if app.NoneButton.Value == true
                Peak = {};
                Location = {};
                Intensity = {};
                FWHM = {};
                app.UITable.Data = [Peak Location Intensity FWHM];
                setappdata(app.UITable,'Data',[Peak Location Intensity FWHM]);
            elseif app.GrapheneButton.Value == true
                Peak = {'D'; 'G'; '2D'};
                Location = num2cell([1350; 1580; 2690]);
                Intensity = num2cell([NaN ; NaN; NaN]);
                FWHM = num2cell([NaN ; NaN; NaN]);
                app.UITable.Data = [Peak Location Intensity FWHM];
                setappdata(app.UITable,'Data',[Peak Location Intensity FWHM]);
            elseif app.MoS2Button.Value == true
                Peak = {'E1g'; 'E2g'; 'A1g'};
                Location = num2cell([286; 383; 407]);
                Intensity = num2cell([NaN ; NaN; NaN]);
                FWHM = num2cell([NaN ; NaN; NaN]);
                app.UITable.Data = [Peak Location Intensity FWHM];
                setappdata(app.UITable,'Data',[Peak Location Intensity FWHM]);
            elseif app.PhosphoreneButton.Value == true
                Peak = {'A1g'; 'B2g'; 'A2g'};
                Location = num2cell([363; 440; 471]);
                Intensity = num2cell([NaN ; NaN; NaN]);
                FWHM = num2cell([NaN ; NaN; NaN]);
                app.UITable.Data = [Peak Location Intensity FWHM];
                setappdata(app.UITable,'Data',[Peak Location Intensity FWHM]);
            elseif app.WS2Button.Value == true
                Peak = {'LA(M)'; 'LA(K)'; 'LA(K)+E2g2';'A1g-LA(M)'; '2LA(M)-3E2g2'; '2LA(M)-2E2g2';'ML'; '2LA(M)-E2g2'; '2LA(M)';'E2g1'; 'A1g'; 'A1g+LA(M)';'4LA(M)'};
                Location = num2cell([176; 190; 213; 230; 264; 297; 312; 324; 352; 355; 417; 582; 704]);
                Intensity = num2cell([NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN]);
                FWHM = num2cell([NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN]);
                app.UITable.Data = [Peak Location Intensity FWHM];
                setappdata(app.UITable,'Data',[Peak Location Intensity FWHM]);
            end
        end

        % Value changed function: SiliconCheckBox
        function uiaddsilicon(app, event)
            value = app.SiliconCheckBox.Value;
            if value == 1
                RData = getappdata(app.UITable,'Data');
                RDataSi = [RData; {'Si'} num2cell([521 NaN NaN])];
                app.UITable.Data = RDataSi;
                setappdata(app.UITable,'Data',RDataSi);
            elseif value == 0
                RDataN = getappdata(app.UITable,'Data');
                sizeRData = size(RDataN);
                ysizeRData = sizeRData(1);
                RDataN(ysizeRData,:) = [];
                app.UITable.Data = RDataN;
                setappdata(app.UITable,'Data',RDataN);
            end
        end

        % Button pushed function: FitButton
        function uifit(app, event)
             xdatafit = getappdata(app.UIFigure,'xtransfer2');
             ydatafit = getappdata(app.UIFigure,'ytransfer2');
             DataAll = getappdata(app.UITable, 'Data');
             sizeDataAll = size(DataAll);
             ny = sizeDataAll(1);
             nx = sizeDataAll(2);
             x0sub1 = zeros(1,ny);
             x0sub2 = zeros(1,ny);
             x0sub3 = zeros(1,ny) + 5;
             for index = 1:ny
               value = DataAll{index,2};
               logicalmatch = xdatafit < value(1);
               logicalindex = find(logicalmatch == 0, 1, 'first');
               x0sub1(index) = ydatafit(logicalindex);
               x0sub2(index) = value;
             end
             x0 = [x0sub1 x0sub2 x0sub3];
             fun1 = @(xi,xdatafit)(xi(1)./(1+(((xdatafit-xi(2))./xi(3)).^2)));
             if ny == 1
                 fun = @(x,xdatafit)(x(1)./(1+(((xdatafit-x(2))./x(3)).^2)));
             elseif ny == 2
                 fun = @(x,xdatafit)(x(1)./(1+(((xdatafit-x(3))./x(5)).^2)))+(x(2)./(1+(((xdatafit-x(4))./x(6)).^2)));
             elseif ny == 3
                 fun = @(x,xdatafit)(x(1)./(1+(((xdatafit-x(4))./x(7)).^2)))+(x(2)./(1+(((xdatafit-x(5))./x(8)).^2)))+(x(3)./(1+(((xdatafit-x(6))./x(9)).^2)));
             elseif ny == 4
                 fun = @(x,xdatafit)(x(1)./(1+(((xdatafit-x(5))./x(9)).^2)))+(x(2)./(1+(((xdatafit-x(6))./x(10)).^2)))+(x(3)./(1+(((xdatafit-x(7))./x(11)).^2)))+(x(4)./(1+(((xdatafit-x(8))./x(12)).^2)));
             elseif ny == 5
                 fun = @(x,xdatafit)(x(1)./(1+(((xdatafit-x(6))./x(11)).^2)))+(x(2)./(1+(((xdatafit-x(7))./x(12)).^2)))+(x(3)./(1+(((xdatafit-x(8))./x(13)).^2)))+(x(4)./(1+(((xdatafit-x(9))./x(14)).^2)))+(x(5)./(1+(((xdatafit-x(10))./x(15)).^2)));
             elseif ny == 6
                 fun = @(x,xdatafit)(x(1)./(1+(((xdatafit-x(7))./x(13)).^2)))+(x(2)./(1+(((xdatafit-x(8))./x(14)).^2)))+(x(3)./(1+(((xdatafit-x(9))./x(15)).^2)))+(x(4)./(1+(((xdatafit-x(10))./x(16)).^2)))+(x(5)./(1+(((xdatafit-x(11))./x(17)).^2)))+(x(6)./(1+(((xdatafit-x(12))./x(18)).^2)));
             elseif ny == 13
                 fun = @(x,xdatafit)(x(1)./(1+(((xdatafit-x(14))./x(27)).^2)))+(x(2)./(1+(((xdatafit-x(15))./x(28)).^2)))+(x(3)./(1+(((xdatafit-x(16))./x(29)).^2)))+(x(4)./(1+(((xdatafit-x(17))./x(30)).^2)))+(x(5)./(1+(((xdatafit-x(18))./x(31)).^2)))+(x(6)./(1+(((xdatafit-x(19))./x(32)).^2)))+(x(7)./(1+(((xdatafit-x(20))./x(33)).^2)))+(x(8)./(1+(((xdatafit-x(21))./x(34)).^2)))+(x(9)./(1+(((xdatafit-x(22))./x(35)).^2)))+(x(10)./(1+(((xdatafit-x(23))./x(36)).^2)))+(x(11)./(1+(((xdatafit-x(24))./x(37)).^2)))+(x(12)./(1+(((xdatafit-x(25))./x(38)).^2)))+(x(13)./(1+(((xdatafit-x(26))./x(39)).^2)));
             elseif ny == 14
                 fun = @(x,xdatafit)(x(1)./(1+(((xdatafit-x(15))./x(29)).^2)))+(x(2)./(1+(((xdatafit-x(16))./x(30)).^2)))+(x(3)./(1+(((xdatafit-x(17))./x(31)).^2)))+(x(4)./(1+(((xdatafit-x(18))./x(32)).^2)))+(x(5)./(1+(((xdatafit-x(19))./x(33)).^2)))+(x(6)./(1+(((xdatafit-x(20))./x(34)).^2)))+(x(7)./(1+(((xdatafit-x(21))./x(35)).^2)))+(x(8)./(1+(((xdatafit-x(22))./x(36)).^2)))+(x(9)./(1+(((xdatafit-x(23))./x(37)).^2)))+(x(10)./(1+(((xdatafit-x(24))./x(38)).^2)))+(x(11)./(1+(((xdatafit-x(25))./x(39)).^2)))+(x(12)./(1+(((xdatafit-x(26))./x(40)).^2)))+(x(13)./(1+(((xdatafit-x(27))./x(41)).^2)))+(x(14)./(1+(((xdatafit-x(28))./x(42)).^2)));
             end
             
             % Execute fit
             [x,resnorm,residual,exitflag,output,lambda,J] = lsqcurvefit(fun,x0,xdatafit,ydatafit);            
             
             % Extract location, intensity, and FWHM fit results
             Peak = DataAll(1:ny,1);
             IntensityArray = x(1:ny)';
             LocationArray = x(ny+1:2*ny)';
             FWHMArray = (x((2*ny)+1:3*ny)').*2;
             Intensity = num2cell(IntensityArray);
             Location = num2cell(LocationArray);
             FWHM = num2cell(FWHMArray);
             app.UITable.Data = [Peak Location Intensity FWHM];
             setappdata(app.UITable,'Data',[Peak Location Intensity FWHM]);
             
             % Calculate errors
             ci = nlparci(x,residual,'jacobian',J);
             [ciy,cix] = size(ci);             
             for i = 1:ciy
                 errorci(i) = (ci(i,2)-ci(i,1))/2;
             end
             IntensityErrorArray = errorci(1:ny)';
             LocationErrorArray = errorci(ny+1:2*ny)';
             FWHMErrorArray = (errorci((2*ny)+1:3*ny)').*2;
             IntensityError = num2cell(IntensityErrorArray);
             LocationError = num2cell(LocationErrorArray);
             FWHMError = num2cell(FWHMErrorArray);   
             setappdata(app.UITable,'Data2',[Peak LocationError IntensityError FWHMError]);
                       
             % plot(app.UIAxes,xdatafit,ydatafit,xdatafit,fun1(xE1g,xdatafit))
             % app.UIAxes.XLim = [xmin xmax];
             % app.UIAxes.YLim = [ymin ymax];
        end

        % Selection changed function: ButtonGroup
        function uivaluesorerros(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;            
            if app.ValuesButton.Value == true
                transfer1 = getappdata(app.UITable,'Data');
                [transfer1y,transfer1x] = size(transfer1);
                Peak = transfer1(1:transfer1y,1);
                LocationError = transfer1(1:transfer1y,2);
                IntensityError = transfer1(1:transfer1y,3);
                FWHMError = transfer1(1:transfer1y,4);           
                app.UITable.Data = [Peak LocationError IntensityError FWHMError];                         
            elseif app.ErrorsButton.Value == true
                transfer2 = getappdata(app.UITable,'Data2');
                [transfer2y,transfer2x] = size(transfer2);
                Peak = transfer2(1:transfer2y,1);
                LocationError = transfer2(1:transfer2y,2);
                IntensityError = transfer2(1:transfer2y,3);
                FWHMError = transfer2(1:transfer2y,4);           
                app.UITable.Data = [Peak LocationError IntensityError FWHMError];                
            end        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Raman Shift (cm-1)');
            ylabel(app.UIAxes, 'Intensity (a.u.)');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Peak'; 'Location'; 'Intensity'; 'FWHM'};
            app.UITable.RowName = {};
            app.PickYourMaterialButtonGroup = uibuttongroup(app.UIFigure);
            app.PickYourMaterialButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @uipickmaterial, true);
            app.PickYourMaterialButtonGroup.Title = '1. Pick Your Material:';
            app.GrapheneButton = uiradiobutton(app.PickYourMaterialButtonGroup);
            app.GrapheneButton.Text = 'Graphene';
            app.MoS2Button = uiradiobutton(app.PickYourMaterialButtonGroup);
            app.MoS2Button.Text = 'MoS2';
            app.PhosphoreneButton = uiradiobutton(app.PickYourMaterialButtonGroup);
            app.PhosphoreneButton.Text = 'Phosphorene';
            app.WS2Button = uiradiobutton(app.PickYourMaterialButtonGroup);
            app.WS2Button.Text = 'WS2';
            app.SiliconCheckBox = uicheckbox(app.PickYourMaterialButtonGroup);
            app.SiliconCheckBox.ValueChangedFcn = createCallbackFcn(app, @uiaddsilicon, true);
            app.SiliconCheckBox.Text = 'Silicon';
            app.NoneButton = uiradiobutton(app.PickYourMaterialButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.BaselineButton = uibutton(app.UIFigure, 'push');
            app.BaselineButton.ButtonPushedFcn = createCallbackFcn(app, @uibaseline, true);
            app.BaselineButton.Text = '3. Baseline';
            app.FitButton = uibutton(app.UIFigure, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @uifit, true);
            app.FitButton.Text = '4. Fit!';
            app.RamanBaselineandFitToolboxLabel = uilabel(app.UIFigure);
            app.RamanBaselineandFitToolboxLabel.Text = 'Raman Baseline and Fit Toolbox';
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @uiimportdata, true);
            app.ImportDataButton.Text = '2. Import Data';
            app.SmoothnessParameterEditFieldLabel = uilabel(app.UIFigure);
            app.SmoothnessParameterEditFieldLabel.Text = 'Smoothness Parameter';
            app.SmoothnessParameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmoothnessParameterEditField.Value = 1000;
            app.MinimumDifferenceEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumDifferenceEditFieldLabel.Text = 'Minimum Difference';
            app.MinimumDifferenceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumDifferenceEditField.Value = 1e-06;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @uivaluesorerros, true);
            app.ValuesButton = uiradiobutton(app.ButtonGroup);
            app.ValuesButton.Text = 'Values';
            app.ValuesButton.Value = true;
            app.ErrorsButton = uiradiobutton(app.ButtonGroup);
            app.ErrorsButton.Text = 'Errors';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MatsLab2D()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end